classdef tableau_interactif < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        NbvillesEditFieldLabel  matlab.ui.control.Label
        nb_villes               matlab.ui.control.NumericEditField
        GnrerlesvillesButton    matlab.ui.control.Button
        GloutonButton           matlab.ui.control.Button
        RecuitButton            matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        axe_glouton             matlab.ui.control.UIAxes
        axe_recuit              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        villes % Description
        dist_matrice
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GnrerlesvillesButton
        function GnrerlesvillesButtonPushed(app, event)
            
            app.villes = rand(app.nb_villes.Value,2);
            app.dist_matrice =  dist(app.villes');

            scatter(app.axe_glouton,app.villes(:,1),app.villes(:,2),'o')
            scatter(app.axe_recuit,app.villes(:,1),app.villes(:,2),'o')
        end

        % Button pushed function: GloutonButton
        function GloutonButtonPushed(app, event)
               
            ax = app.axe_glouton;
            
            chemin_glouton = vdc_glouton(app.villes,app.dist_matrice);
            nb_ville = length(app.villes);
            pos_chemin = app.villes([chemin_glouton,1],:);
            
            
            for i=1:nb_ville
                line(ax,pos_chemin(i:i+1,1),pos_chemin(i:i+1,2));
                text(ax,pos_chemin(i,1)+0.01,pos_chemin(i,2)-0.01,num2str(i))
                pause(4/nb_ville)
            end

        end

        % Button pushed function: RecuitButton
        function RecuitButtonPushed(app, event)
            t_palier=ones(1,5)*20000;
            T_palier=[0,0,0,0,0];
            % T_palier=[1,0.1,0.01,0.001,0.0001];
            % T_palier=[0,0,0,0,0];
            T=[];
            for i=1:5
                T = [T,ones(1,t_palier(i))*T_palier(i)];
            end

            % plot(T)
            
            %%
            ax = app.axe_recuit;
            vdc_recuit_simule(app.villes,T,1,ax);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NbvillesEditFieldLabel = uilabel(app.LeftPanel);
            app.NbvillesEditFieldLabel.Text = 'Nb. villes';
            app.nb_villes = uieditfield(app.LeftPanel, 'numeric');
            app.GnrerlesvillesButton = uibutton(app.LeftPanel, 'push');
            app.GnrerlesvillesButton.ButtonPushedFcn = createCallbackFcn(app, @GnrerlesvillesButtonPushed, true);
            app.GnrerlesvillesButton.Text = 'Générer les villes';
            app.GloutonButton = uibutton(app.LeftPanel, 'push');
            app.GloutonButton.ButtonPushedFcn = createCallbackFcn(app, @GloutonButtonPushed, true);
            app.GloutonButton.Text = 'Glouton';
            app.RecuitButton = uibutton(app.LeftPanel, 'push');
            app.RecuitButton.ButtonPushedFcn = createCallbackFcn(app, @RecuitButtonPushed, true);
            app.RecuitButton.Text = 'Recuit';
            app.RightPanel = uipanel(app.GridLayout);
            app.axe_glouton = uiaxes(app.RightPanel);
            title(app.axe_glouton, 'Title')
            xlabel(app.axe_glouton, 'X')
            ylabel(app.axe_glouton, 'Y')
            app.axe_recuit = uiaxes(app.RightPanel);
            title(app.axe_recuit, 'Title')
            xlabel(app.axe_recuit, 'X')
            ylabel(app.axe_recuit, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tableau_interactif

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end