classdef essaim_interactif < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NbparticulesEditFieldLabel      matlab.ui.control.Label
        Nbparticules                    matlab.ui.control.NumericEditField
        FonctionoptimiserDropDownLabel  matlab.ui.control.Label
        FonctionoptimiserDropDown       matlab.ui.control.DropDown
        InitialiserlesparticulesButton  matlab.ui.control.Button
        LimiteaxesEditFieldLabel        matlab.ui.control.Label
        LimitAxes                       matlab.ui.control.NumericEditField
        RunSwitchLabel                  matlab.ui.control.Label
        RunSwitch                       matlab.ui.control.Switch
        InertieomegaLabel               matlab.ui.control.Label
        omega                           matlab.ui.control.NumericEditField
        AttractionminpersoalphaLabel    matlab.ui.control.Label
        alpha                           matlab.ui.control.NumericEditField
        AttractionminabsolubetaLabel    matlab.ui.control.Label
        beta                            matlab.ui.control.NumericEditField
        VitesseEditFieldLabel           matlab.ui.control.Label
        Vitesse                         matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        map_fig                         matlab.ui.control.UIAxes
        g_fig                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        particules % Description
        g_plot
        temps_min_global
        line
        initialise = false;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bound = app.LimitAxes.Value;
            x_min = -bound;x_max = bound;y_min = -bound;y_max = bound;
            pas = (x_max-x_min)/1000;
           
            x_grid = x_min:pas:x_max;
            y_grid = y_min:pas:y_max;
            [X,Y] = meshgrid(x_grid,y_grid);
            G = cost_function({X,Y},app.FonctionoptimiserDropDown.Value);
            
            cla(app.map_fig,'reset')
            imagesc(app.map_fig,x_grid,y_grid,G)
            xlim(app.map_fig,[x_min x_max])
            ylim(app.map_fig,[y_min y_max])
            hold(app.map_fig,'on');

        end

        % Button pushed function: InitialiserlesparticulesButton
        function InitialiserlesparticulesButtonPushed(app, event)
            
            
            bound = app.LimitAxes.Value;
            x_min = -bound;x_max = bound;y_min = -bound;y_max = bound;
            pas = (x_max-x_min)/1000;
            
            n_part=app.Nbparticules.Value; 
            app.particules.pos=zeros(n_part,2);
            app.particules.v=zeros(n_part,2);
            
            app.particules.pos_opt=zeros(n_part,2);
            app.particules.g_opt=zeros(n_part,1);
            
            app.particules.v_max = app.Vitesse.Value*pas;
            app.particules.iter = 0;
            
            app.particules.pos_opt_global = [x_min+rand*(x_max-x_min),y_min+rand*(y_max-y_min)];
            app.particules.g_opt_global = cost_function({app.particules.pos_opt_global(1),app.particules.pos_opt_global(2)},app.FonctionoptimiserDropDown.Value);
            
            
            for i=1:n_part
                app.particules.pos(i,:) = [x_min+rand*(x_max-x_min),y_min+rand*(y_max-y_min)];
                app.particules.pos_opt(i,:) = app.particules.pos(i,:);
                
                app.particules.g_opt(i) = cost_function({app.particules.pos(i,1),app.particules.pos(i,2)},app.FonctionoptimiserDropDown.Value);
                app.particules.v(i,:) =  [pas*rand,pas*rand]; 
                if app.particules.g_opt(i) < app.particules.g_opt_global
                    app.particules.g_opt_global = app.particules.g_opt(i);
                    app.particules.pos_opt_global = app.particules.pos(i,:);
                end
            
            end
            
            
            app.g_plot =[];
            app.temps_min_global=0;
            
            if app.initialise
                delete(app.line)    
            end
            pos = app.particules.pos;
            vit = app.particules.v;
            app.line = plot(app.map_fig,pos(:,1),pos(:,2),'k.','MarkerSize',10);
            
            app.initialise= true;

        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            
            bound = app.LimitAxes.Value;
            x_min = -bound;x_max = bound;
            pas = (x_max-x_min)/1000;
            app.particules.v_max = app.Vitesse.Value*pas;

            while strcmp(get(app.RunSwitch,'Value'),'On')
                n_part=app.Nbparticules.Value;
                

                for i = 1:n_part
                    r = rand(1,2);
                    app.particules.v(i,:) = app.omega.Value*app.particules.v(i,:) + r(1)*app.alpha.Value*(app.particules.pos_opt(i,:)-app.particules.pos(i,:)) + r(2)*app.beta.Value*(app.particules.pos_opt_global-app.particules.pos(i,:));  
                    
                    if app.particules.v_max<norm(app.particules.v(i,:))
                        app.particules.v(i,:) = app.particules.v(i,:)*app.particules.v_max/norm(app.particules.v(i,:));
                    end
            
                    app.particules.pos(i,:) = app.particules.pos(i,:) + app.particules.v(i,:);
               
                    g = cost_function({app.particules.pos(i,1),app.particules.pos(i,2)},app.FonctionoptimiserDropDown.Value);
                    
                    if g<app.particules.g_opt(i)
                        app.particules.g_opt(i) = g;
                        app.particules.pos_opt(i,:) = app.particules.pos(i,:);
                        if g<app.particules.g_opt_global
                            app.particules.g_opt_global = g;
                            app.particules.pos_opt_global = app.particules.pos(i,:);
                            app.temps_min_global = app.particules.iter;
                            
                        end
                    end
                    
                end
                
                if rem(app.particules.iter,3)==0

                    delete(app.line);
    
                
                    app.line = plot(app.map_fig,app.particules.pos(:,1),app.particules.pos(:,2),'k.','MarkerSize',10);

                    
                    app.g_plot = [app.g_plot, app.particules.g_opt_global];
                    plot(app.g_fig,app.g_plot)
                    text(app.g_fig,0.6,0.7,sprintf(' min_{global} = %.3f \n atteint en (%.2f,%.2f) \n au bout de %.1f',[app.particules.g_opt_global,app.particules.pos_opt_global,app.temps_min_global]),'units','normalized')
                    drawnow;
                end
                
%                 if app.particules.iter > 100 && abs((app.g_plot(end) - mean(app.g_plot(app.particules.iter-100:end))))< 1e-10
%                     break
%                 end

                app.particules.iter = app.particules.iter+1;
            end
        end

        % Value changed function: FonctionoptimiserDropDown
        function FonctionoptimiserDropDownValueChanged(app, event)
            bound = app.LimitAxes.Value;
            x_min = -bound;x_max = bound;y_min = -bound;y_max = bound;
            pas = (x_max-x_min)/1000;
           
            x_grid = x_min:pas:x_max;
            y_grid = y_min:pas:y_max;
            [X,Y] = meshgrid(x_grid,y_grid);
            G = cost_function({X,Y},app.FonctionoptimiserDropDown.Value);
            
            cla(app.map_fig,'reset')
            imagesc(app.map_fig,x_grid,y_grid,G)
            xlim(app.map_fig,[x_min x_max])
            ylim(app.map_fig,[y_min y_max])
            hold(app.map_fig,'on');
            
            if app.initialise
                app.line = plot(app.map_fig,app.particules.pos(:,1),app.particules.pos(:,2),'k.','MarkerSize',10);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: LimitAxes
        function LimitAxesValueChanged(app, event)

            FonctionoptimiserDropDownValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NbparticulesEditFieldLabel = uilabel(app.LeftPanel);
            app.NbparticulesEditFieldLabel.Text = 'Nb. particules';
            app.Nbparticules = uieditfield(app.LeftPanel, 'numeric');
            app.Nbparticules.Value = 10;
            app.FonctionoptimiserDropDownLabel = uilabel(app.LeftPanel);
            app.FonctionoptimiserDropDownLabel.Text = 'Fonction à optimiser';
            app.FonctionoptimiserDropDown = uidropdown(app.LeftPanel);
            app.FonctionoptimiserDropDown.Items = {'carre', 'drop', 'easom', 'ackley', 'crossit'};
            app.FonctionoptimiserDropDown.ValueChangedFcn = createCallbackFcn(app, @FonctionoptimiserDropDownValueChanged, true);
            app.FonctionoptimiserDropDown.Value = 'drop';
            app.InitialiserlesparticulesButton = uibutton(app.LeftPanel, 'push');
            app.InitialiserlesparticulesButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiserlesparticulesButtonPushed, true);
            app.InitialiserlesparticulesButton.Text = 'Initialiser les particules';
            app.LimiteaxesEditFieldLabel = uilabel(app.LeftPanel);
            app.LimiteaxesEditFieldLabel.Text = 'Limite axes';
            app.LimitAxes = uieditfield(app.LeftPanel, 'numeric');
            app.LimitAxes.ValueChangedFcn = createCallbackFcn(app, @LimitAxesValueChanged, true);
            app.LimitAxes.Value = 5;
            app.RunSwitchLabel = uilabel(app.LeftPanel);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.InertieomegaLabel = uilabel(app.LeftPanel);
            app.InertieomegaLabel.Text = 'Inertie : omega';
            app.omega = uieditfield(app.LeftPanel, 'numeric');
            app.omega.Value = 1;
            app.AttractionminpersoalphaLabel = uilabel(app.LeftPanel);
            app.AttractionminpersoalphaLabel.Text = 'Attraction min. perso : alpha';
            app.alpha = uieditfield(app.LeftPanel, 'numeric');
            app.alpha.Value = 2;
            app.AttractionminabsolubetaLabel = uilabel(app.LeftPanel);
            app.AttractionminabsolubetaLabel.Text = 'Attraction min. absolu : beta';
            app.beta = uieditfield(app.LeftPanel, 'numeric');
            app.beta.Value = 2.1;
            app.VitesseEditFieldLabel = uilabel(app.LeftPanel);
            app.VitesseEditFieldLabel.Text = 'Vitesse';
            app.Vitesse = uieditfield(app.LeftPanel, 'numeric');
            app.Vitesse.Value = 2;
            app.RightPanel = uipanel(app.GridLayout);
            app.map_fig = uiaxes(app.RightPanel);
            title(app.map_fig, '')
            xlabel(app.map_fig, '')
            ylabel(app.map_fig, '')
            app.g_fig = uiaxes(app.RightPanel);
            title(app.g_fig, 'Evolution du minimum')
            xlabel(app.g_fig, '')
            ylabel(app.g_fig, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = essaim_interactif

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end